classdef back_sys_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        SelectButton                matlab.ui.control.Button
        Step1SelectinputfilesLabel  matlab.ui.control.Label
        Step2SelectalgorithmLabel   matlab.ui.control.Label
        BlackHoleButton             matlab.ui.control.Button
        GeneticButton               matlab.ui.control.Button
        SimulatedAnnealingButton    matlab.ui.control.Button
        NoticeBoardTextAreaLabel    matlab.ui.control.Label
        NoticeBoardTextArea         matlab.ui.control.TextArea
        Step3ParametersettingLabel  matlab.ui.control.Label
        OptimizationWeightofRechagredEnergyLabel  matlab.ui.control.Label
        AlgorithmComputationalCostLevelSpinnerLabel  matlab.ui.control.Label
        AlgorithmRandomnessLevelSpinnerLabel  matlab.ui.control.Label
        OptimizationWeightPDVDischargeenergySlider  matlab.ui.control.Slider
        AlgorithmComputationalCostLevelSpinner  matlab.ui.control.Spinner
        AlgorithmComputationalCostLevelSpinner_2  matlab.ui.control.Spinner
        ClearpageButton             matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            [file, path] = uigetfile({'*.csv'; '*.txt'}, 'File Selector');
            if isequal(file, 0)
                app.NoticeBoardTextArea.Value = [app.NoticeBoardTextArea.Value; 'User selection cancelled'];
                drawnow;
            else
                full_path = fullfile(path, file);
                if exist(full_path, 'file')
                    app.NoticeBoardTextArea.Value = [...
                        app.NoticeBoardTextArea.Value ...
                        ; 'User selected ', file ...
                        ; 'Red for Sensor Nodes requested to be recharged' ...
                        ; 'Green for Sensor Nodes can work'];
                    drawnow;
                    in_dat = readtable(full_path);
                    dat_x = in_dat{:,1};
                    dat_y = in_dat{:,2};
                    dat_w = in_dat{:,end};
                    for i = 1:length(dat_w)
                        if dat_w(i) <= 4
                            dat_w(i) = 2;
                        else
                            dat_w(i) = 6;
                        end
                    end
                    scatter(app.UIAxes, dat_x, dat_y, 10, dat_w, '*');
                    c = colorbar(app.UIAxes);
                    colormap(app.UIAxes, [0.8 0 0; 0 0.7 0]);
                    c.Label.String = 'SN remain energy level';
                    app.UIAxes.FontSize = 10;
                end
            end
        end

        % Button pushed function: ClearpageButton
        function ClearpageButtonPushed(app, event)
            app.NoticeBoardTextArea.Value = '';
            cla(app.UIAxes);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {478, 478};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {492, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Settings';
            app.SelectButton = uibutton(app.LeftPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.Step1SelectinputfilesLabel = uilabel(app.LeftPanel);
            app.Step1SelectinputfilesLabel.Text = 'Step 1 - Select input files';
            app.Step2SelectalgorithmLabel = uilabel(app.LeftPanel);
            app.Step2SelectalgorithmLabel.Text = 'Step 2 - Select algorithm';
            app.BlackHoleButton = uibutton(app.LeftPanel, 'push');
            app.BlackHoleButton.Text = 'Black Hole';
            app.GeneticButton = uibutton(app.LeftPanel, 'push');
            app.GeneticButton.Text = 'Genetic';
            app.SimulatedAnnealingButton = uibutton(app.LeftPanel, 'push');
            app.SimulatedAnnealingButton.Text = 'Simulated Annealing';
            app.NoticeBoardTextAreaLabel = uilabel(app.LeftPanel);
            app.NoticeBoardTextAreaLabel.Text = 'Notice Board';
            app.NoticeBoardTextArea = uitextarea(app.LeftPanel);
            app.NoticeBoardTextArea.Value = {'Welcome to use Optimal Drone Recharging Scheduling (ODRS) System!'};
            app.Step3ParametersettingLabel = uilabel(app.LeftPanel);
            app.Step3ParametersettingLabel.Text = 'Step 3 - Parameter setting';
            app.OptimizationWeightofRechagredEnergyLabel = uilabel(app.LeftPanel);
            app.OptimizationWeightofRechagredEnergyLabel.Text = 'Optimization Weight of Rechagred Energy';
            app.AlgorithmComputationalCostLevelSpinnerLabel = uilabel(app.LeftPanel);
            app.AlgorithmComputationalCostLevelSpinnerLabel.Text = 'Algorithm Computational Cost Level';
            app.AlgorithmRandomnessLevelSpinnerLabel = uilabel(app.LeftPanel);
            app.AlgorithmRandomnessLevelSpinnerLabel.Text = 'Algorithm Randomness Level';
            app.OptimizationWeightPDVDischargeenergySlider = uislider(app.LeftPanel);
            app.AlgorithmComputationalCostLevelSpinner = uispinner(app.LeftPanel);
            app.AlgorithmComputationalCostLevelSpinner_2 = uispinner(app.LeftPanel);
            app.ClearpageButton = uibutton(app.LeftPanel, 'push');
            app.ClearpageButton.ButtonPushedFcn = createCallbackFcn(app, @ClearpageButtonPushed, true);
            app.ClearpageButton.Text = 'Clear page';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'WSN Map Visualization';
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'Longitude')
            ylabel(app.UIAxes, 'Latitude')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = back_sys_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end